classdef mst < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        StartButton                     matlab.ui.control.Button
        MinimumSpanningTreeParameterLabel  matlab.ui.control.Label
        NumberofNodeMin6Label           matlab.ui.control.Label
        numNode                         matlab.ui.control.NumericEditField
        XValueEditFieldLabel            matlab.ui.control.Label
        XVal                            matlab.ui.control.EditField
        YValueLabel                     matlab.ui.control.Label
        YVal                            matlab.ui.control.EditField
        RandomCoordinateSwitchLabel     matlab.ui.control.Label
        RandomSwitch                    matlab.ui.control.Switch
        ManualCoordinateLabel           matlab.ui.control.Label
        RandomCoordinateLabel           matlab.ui.control.Label
        RangeFromLabel                  matlab.ui.control.Label
        RangeFrom                       matlab.ui.control.NumericEditField
        RangeTo                         matlab.ui.control.NumericEditField
        ToLabel                         matlab.ui.control.Label
        ParticleSwarmOptimizationParameterLabel  matlab.ui.control.Label
        MaxIterationEditFieldLabel      matlab.ui.control.Label
        MaxIter                         matlab.ui.control.NumericEditField
        SwarmPopulationEditFieldLabel   matlab.ui.control.Label
        SwarmPop                        matlab.ui.control.NumericEditField
        InertiaWeightEditFieldLabel     matlab.ui.control.Label
        InertiaW                        matlab.ui.control.NumericEditField
        InertiaWeightDampingRatioEditFieldLabel  matlab.ui.control.Label
        InertiaWD                       matlab.ui.control.NumericEditField
        PersonalLearningCoefficientLabel  matlab.ui.control.Label
        PCoefficient                    matlab.ui.control.NumericEditField
        GlobalLearningCoefficientLabel  matlab.ui.control.Label
        GCoefficient                    matlab.ui.control.NumericEditField
        MutationRateEditFieldLabel      matlab.ui.control.Label
        MutationRate                    matlab.ui.control.NumericEditField
        CenterPanel                     matlab.ui.container.Panel
        GraphView                       matlab.ui.control.UIAxes
        BestCostLine                    matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        OutputTextAreaLabel             matlab.ui.control.Label
        OutputTextArea                  matlab.ui.control.TextArea
        LastIterationBestCostUpdateEditFieldLabel  matlab.ui.control.Label
        LastIterBestCost                matlab.ui.control.EditField
        LastIterationBestCostElapseTimeLabel  matlab.ui.control.Label
        LastIterBestTime                matlab.ui.control.EditField
        TotalElapseTime                 matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        Property % Description
        input
    end
    
    methods (Access = private)
        
        function [inputerror, errormsg] = inputCheck(app)
            app.input.xvalue = str2num(app.XVal.Value);
            app.input.xvalue = int32(app.input.xvalue);
            app.input.xvalue = double(app.input.xvalue);
            app.input.yvalue = str2num(app.YVal.Value);
            app.input.yvalue = int32(app.input.yvalue);
            app.input.yvalue = double(app.input.yvalue);
            inputerror = false;
            errormsg = "";
            if(strcmp(app.RandomSwitch.Value, 'Off'))
                if(numel(app.input.xvalue)<6)
                    inputerror = true;
                    errormsg = "X Value must be numbers with delimiter comma or space(Min 6)"
                end
                if(numel(app.input.yvalue)<6)
                    inputerror = true;
                    errormsg = "Y Value must be numbers with delimiter comma or space(Min 6)"
                end
                if(numel(app.input.xvalue)~=numel(app.input.yvalue))
                    inputerror = true;
                    errormsg = "X and Y value must be equal element number"
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            set(app.StartButton, 'Text', 'Wait');
            set(app.StartButton, 'Enable', 'off');
            app.OutputTextArea.Value = '';
            [inputerror, errormsg] = inputCheck(app);
            if(inputerror)
                msgbox(['Error: ', errormsg]);
            else
                %msgbox(['Sucess: Yo']);
                pso(app);
            end
            set(app.StartButton, 'Text', 'Start');
            set(app.StartButton, 'Enable', 'on');
        end

        % Value changed function: RandomSwitch
        function RandomSwitchValueChanged(app, event)
            value = app.RandomSwitch.Value;
            if(strcmp(value, 'On'))
                app.numNode.Enable = 1;
                app.RangeFrom.Enable = 1;
                app.RangeTo.Enable = 1;
                app.XVal.Enable = 0;
                app.YVal.Enable = 0;
            else
                app.numNode.Enable = 0;
                app.RangeFrom.Enable = 0;
                app.RangeTo.Enable = 0;
                app.XVal.Enable = 1;
                app.YVal.Enable = 1;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {715, 715, 715};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {715, 715};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {349, '1x', 361};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.MinimumSpanningTreeParameterLabel = uilabel(app.LeftPanel);
            app.MinimumSpanningTreeParameterLabel.Text = 'Minimum Spanning Tree Parameter';
            app.NumberofNodeMin6Label = uilabel(app.LeftPanel);
            app.NumberofNodeMin6Label.Text = 'Number of Node(Min 6)';
            app.numNode = uieditfield(app.LeftPanel, 'numeric');
            app.numNode.Limits = [6 Inf];
            app.numNode.ValueDisplayFormat = '%.0f';
            app.numNode.Value = 20;
            app.XValueEditFieldLabel = uilabel(app.LeftPanel);
            app.XValueEditFieldLabel.Text = 'X Value';
            app.XVal = uieditfield(app.LeftPanel, 'text');
            app.XVal.Enable = 'off';
            app.YValueLabel = uilabel(app.LeftPanel);
            app.YValueLabel.Text = 'Y Value';
            app.YVal = uieditfield(app.LeftPanel, 'text');
            app.YVal.Enable = 'off';
            app.RandomCoordinateSwitchLabel = uilabel(app.LeftPanel);
            app.RandomCoordinateSwitchLabel.Text = 'Random Coordinate';
            app.RandomSwitch = uiswitch(app.LeftPanel, 'slider');
            app.RandomSwitch.ValueChangedFcn = createCallbackFcn(app, @RandomSwitchValueChanged, true);
            app.RandomSwitch.Value = 'On';
            app.ManualCoordinateLabel = uilabel(app.LeftPanel);
            app.ManualCoordinateLabel.Text = 'Manual Coordinate';
            app.RandomCoordinateLabel = uilabel(app.LeftPanel);
            app.RandomCoordinateLabel.Text = 'Random Coordinate';
            app.RangeFromLabel = uilabel(app.LeftPanel);
            app.RangeFromLabel.Text = 'Range      From';
            app.RangeFrom = uieditfield(app.LeftPanel, 'numeric');
            app.RangeFrom.ValueDisplayFormat = '%.0f';
            app.RangeTo = uieditfield(app.LeftPanel, 'numeric');
            app.RangeTo.ValueDisplayFormat = '%.0f';
            app.RangeTo.Value = 100;
            app.ToLabel = uilabel(app.LeftPanel);
            app.ToLabel.Text = 'To';
            app.ParticleSwarmOptimizationParameterLabel = uilabel(app.LeftPanel);
            app.ParticleSwarmOptimizationParameterLabel.Text = 'Particle Swarm Optimization Parameter';
            app.MaxIterationEditFieldLabel = uilabel(app.LeftPanel);
            app.MaxIterationEditFieldLabel.Text = 'Max Iteration';
            app.MaxIter = uieditfield(app.LeftPanel, 'numeric');
            app.MaxIter.Limits = [1 Inf];
            app.MaxIter.ValueDisplayFormat = '%.0f';
            app.MaxIter.Value = 500;
            app.SwarmPopulationEditFieldLabel = uilabel(app.LeftPanel);
            app.SwarmPopulationEditFieldLabel.Text = 'Swarm Population';
            app.SwarmPop = uieditfield(app.LeftPanel, 'numeric');
            app.SwarmPop.Limits = [1 Inf];
            app.SwarmPop.ValueDisplayFormat = '%.0f';
            app.SwarmPop.Value = 250;
            app.InertiaWeightEditFieldLabel = uilabel(app.LeftPanel);
            app.InertiaWeightEditFieldLabel.Text = 'Inertia Weight';
            app.InertiaW = uieditfield(app.LeftPanel, 'numeric');
            app.InertiaW.Limits = [0.001 Inf];
            app.InertiaW.ValueDisplayFormat = '%.3f';
            app.InertiaW.Value = 0.02;
            app.InertiaWeightDampingRatioEditFieldLabel = uilabel(app.LeftPanel);
            app.InertiaWeightDampingRatioEditFieldLabel.Text = 'Inertia Weight Damping Ratio';
            app.InertiaWD = uieditfield(app.LeftPanel, 'numeric');
            app.InertiaWD.Limits = [0.001 Inf];
            app.InertiaWD.ValueDisplayFormat = '%.3f';
            app.InertiaWD.Value = 1;
            app.PersonalLearningCoefficientLabel = uilabel(app.LeftPanel);
            app.PersonalLearningCoefficientLabel.Text = 'Personal Learning Coefficient';
            app.PCoefficient = uieditfield(app.LeftPanel, 'numeric');
            app.PCoefficient.Limits = [0.001 Inf];
            app.PCoefficient.ValueDisplayFormat = '%.3f';
            app.PCoefficient.Value = 0.7;
            app.GlobalLearningCoefficientLabel = uilabel(app.LeftPanel);
            app.GlobalLearningCoefficientLabel.Text = 'Global Learning Coefficient';
            app.GCoefficient = uieditfield(app.LeftPanel, 'numeric');
            app.GCoefficient.Limits = [0.001 Inf];
            app.GCoefficient.ValueDisplayFormat = '%.3f';
            app.GCoefficient.Value = 1;
            app.MutationRateEditFieldLabel = uilabel(app.LeftPanel);
            app.MutationRateEditFieldLabel.Text = 'Mutation Rate';
            app.MutationRate = uieditfield(app.LeftPanel, 'numeric');
            app.MutationRate.Limits = [0.001 Inf];
            app.MutationRate.ValueDisplayFormat = '%.3f';
            app.MutationRate.Value = 0.1;
            app.CenterPanel = uipanel(app.GridLayout);
            app.GraphView = uiaxes(app.CenterPanel);
            title(app.GraphView, 'Graph Visual')
            xlabel(app.GraphView, 'X')
            ylabel(app.GraphView, 'Y')
            app.BestCostLine = uiaxes(app.CenterPanel);
            title(app.BestCostLine, 'Best Cost Line')
            xlabel(app.BestCostLine, 'Iteration')
            ylabel(app.BestCostLine, 'Best Cost')
            app.RightPanel = uipanel(app.GridLayout);
            app.OutputTextAreaLabel = uilabel(app.RightPanel);
            app.OutputTextAreaLabel.Text = 'Output';
            app.OutputTextArea = uitextarea(app.RightPanel);
            app.OutputTextArea.Editable = 'off';
            app.LastIterationBestCostUpdateEditFieldLabel = uilabel(app.RightPanel);
            app.LastIterationBestCostUpdateEditFieldLabel.Text = 'Last Iteration Best Cost Update';
            app.LastIterBestCost = uieditfield(app.RightPanel, 'text');
            app.LastIterBestCost.Editable = 'off';
            app.LastIterationBestCostElapseTimeLabel = uilabel(app.RightPanel);
            app.LastIterationBestCostElapseTimeLabel.Text = 'Last Iteration Best Cost Elapse Time';
            app.LastIterBestTime = uieditfield(app.RightPanel, 'text');
            app.LastIterBestTime.Editable = 'off';
            app.TotalElapseTime = uilabel(app.RightPanel);
            app.TotalElapseTime.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mst

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end